classdef GUI_Ispeziona_EDA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ShowpeakNSpinner             matlab.ui.control.Spinner
        ShowpeakNSpinnerLabel        matlab.ui.control.Label
        DetectedpeaksLabel           matlab.ui.control.Label
        mintroughpromEditField       matlab.ui.control.NumericEditField
        mintroughpromEditFieldLabel  matlab.ui.control.Label
        minpeakpromEditField         matlab.ui.control.NumericEditField
        minpeakpromEditFieldLabel    matlab.ui.control.Label
        ProcessButton                matlab.ui.control.Button
        LowPassEditField             matlab.ui.control.NumericEditField
        LowPassEditFieldLabel        matlab.ui.control.Label
        HighPassEditField            matlab.ui.control.NumericEditField
        HighPassEditFieldLabel       matlab.ui.control.Label
        ExporttoworkspaceButton      matlab.ui.control.Button
        InterpolateButton            matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        SamplingrateHzDropDown       matlab.ui.control.DropDown
        SamplingrateHzDropDownLabel  matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        VariablesListBox             matlab.ui.control.ListBox
        VariablesListBoxLabel        matlab.ui.control.Label
        ImportButton                 matlab.ui.control.Button
        axes_down                    matlab.ui.control.UIAxes
        axes_up                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        rawData                     % raw data
        filteredData                % filtered data
        yLims                       % y limits of filtered data
        SR                          % Sampling Rate
        L                           % Length of rawdata
        tx                          % time x (conta anche after deleting/brushing)
        filtered_tx                 % time x (conta anche after deleting/brushing) of filtered data (it can change with respect to tx after filtering)
        workspaceVariables          % variables in the workspace to upload
        lowPass                     % low pass filter
        highPass                    % high pass filter
        peakMinProm                 % min prominence to detect peaks
        minTroughProm               % min prominence to detect troughs
        pLoc                        % location of peaks
        pAmp                        % y value of peaks
        allPeak_start_time          % location of start trough
        allPeak_start_y             % y of start trough
        allPeak_end_time            % location of end trough
        allPeak_end_y               % y of end trough
        EDA_Analysis                % Results of analysis
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SR = str2double(app.SamplingrateHzDropDown.Value);
            app.lowPass = app.LowPassEditField.Value;
            app.highPass = app.HighPassEditField.Value;
            app.peakMinProm = app.minpeakpromEditField.Value;
            app.minTroughProm = app.mintroughpromEditField.Value;


        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.workspaceVariables = evalin('base','who');

            app.VariablesListBox(:).Items = app.workspaceVariables;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            cla(app.axes_up, 'reset')
            cla(app.axes_down, 'reset')
            
            app.rawData = evalin('base', string(app.VariablesListBox.Value));

            % Set tx and Sampling rate
            app.L = length(app.rawData);
            app.tx = linspace(0, app.L/app.SR, app.L);

            % Plot
            plot(app.axes_up, app.tx, app.rawData)
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
        brushIdx = logical(app.axes_up.Children.BrushData);

        brushedData_del = app.rawData;
        brushedData_del(brushIdx) = [];

        app.rawData = brushedData_del;

        app.L = length(app.rawData);

        % Plot
        cla(app.axes_up)
        app.tx = linspace(0, app.L/app.SR, app.L);
        plot(app.axes_up, app.tx, app.rawData)


        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)


            brushIdx = logical(app.axes_up.Children.BrushData);

            brushedData_interp = app.rawData;
            brushedData_interp(brushIdx) = NaN;
            

            [F,TF] = fillmissing(brushedData_interp,'pchip', 'SamplePoints',app.tx);

            brushedData_interp(isnan(brushedData_interp)) = F(TF);

            app.rawData = brushedData_interp;

            app.L = length(app.rawData);
            app.tx = linspace(0, app.L/app.SR, app.L);

            cla(app.axes_up)
            plot(app.axes_up, app.tx, app.rawData)


        end

        % Button pushed function: ExporttoworkspaceButton
        function ExporttoworkspaceButtonPushed(app, event)

            assignin('base', 'EDA_Results', app.EDA_Analysis);
            app.delete;
        end

        % Value changed function: LowPassEditField
        function LowPassEditFieldValueChanged(app, event)
            app.lowPass = app.LowPassEditField.Value;
            
        end

        % Value changed function: HighPassEditField
        function HighPassEditFieldValueChanged(app, event)
            app.highPass = app.HighPassEditField.Value;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            cla(app.axes_down, 'reset')


            %-----------------------------------------------------------------------------------
            %                               PREPROCESS
            %-----------------------------------------------------------------------------------

            % set current signal
            x = app.rawData;
            x(isnan(x)) = [];

            % get tonic mean
            m_t = mean(x);
        
            % highpass
            x = highpass(x, app.highPass, app.SR);
        
            % lowpass
            x = lowpass(x, app.lowPass, app.SR);
        
            % positivizza
            x = x - min(x);

            % Detrend
            x = detrend(x);

            % assegna a app
            app.filteredData = x;

            app.filtered_tx = linspace(0, length(x)/app.SR, length(x));

            % media fasica
            m_f = mean(x);

            % % media fasica senza primi 30 sec (SOLO STROOP VR COOPCOMP)
            % x_meno30 = x;
            % x_meno30(1:30*app.SR) = [];
            % m_f_meno30 = mean(x_meno30);
            
            % trova pikki
            [pAmp, pLoc, pWidth, pProm] = findpeaks(x,  app.SR, ...
                                "MinPeakProminence", app.peakMinProm, "WidthReference", "halfprom");
            app.pLoc = pLoc;
            app.pAmp = pAmp;

            if isempty(pAmp); pAmp = 0; end
            if isempty(pWidth); pWidth = 0; end
            if isempty(pProm); pProm = 0; end
            if isempty(pLoc); pLoc = 0; end
            pNum = numel(pLoc);
            if pLoc == 0; pNum = 0; end
            
            % auc
            if ~isnan(pLoc)
                auc = trapz(x);
            else
                auc = 0;
            end
            
            
            % calc other measures
            ttp = [];
            width_base = [];
            rise_time = [];
            decay_time = [];
            slope_inizio = [];
            slope_fine = [];

            allPeak_start_time = [];
            allPeak_start_y = [];
            allPeak_end_time = [];
            allPeak_end_y = [];

            if pNum > 0
                for p = 1:pNum % for each peak
            
                    currPLoc = pLoc(p);
            
                    currx = x( 1 : currPLoc*app.SR ); % il segnale dall'inizio fino al picco corrente
            
                    % get peak value
                    peakVal = currx(end);
     
                    pLoc_neg = find(islocalmin(x,'minprominence', app.minTroughProm,'minseparation',5*app.SR));
            
                    pLoc_neg = pLoc_neg/app.SR;
            
            
                    % get immediately preceding peak (time)
                    pLoc_neg_start = pLoc_neg(pLoc_neg < currPLoc);
                    if isempty(pLoc_neg_start)
                        pLoc_neg_start = 1; % se non trova trough finale, mettiamo primo valore eda
                    else
                        pLoc_neg_start = pLoc_neg_start(end);
                    end


                    % get immediately following peak (time)
                    pLoc_neg_end = pLoc_neg(pLoc_neg > currPLoc);
                    if isempty(pLoc_neg_end)
                        pLoc_neg_end = numel(x)/app.SR; % se non trova trough finale, mettiamo ultimo valore eda
                    else
                        pLoc_neg_end = pLoc_neg_end(1);
                    end


                    % Get throughs' values
                    troughVal_start = x(round(pLoc_neg_start*app.SR));
                    
                    troughVal_end = x(round(pLoc_neg_end*app.SR));
                    

                    % concatenate all trough values
                    allPeak_start_time = [allPeak_start_time; pLoc_neg_start];
                    allPeak_start_y = [allPeak_start_y; troughVal_start];

                    allPeak_end_time = [allPeak_end_time; pLoc_neg_end];
                    allPeak_end_y = [allPeak_end_y; troughVal_end];


                    % ttp
                    %------------------------
                    ttp = [ttp; peakVal - troughVal_start];
            
                    % width alla base
                    %------------------------
                    width_base = [width_base; pLoc_neg_end - pLoc_neg_start];
            
                    % rise time
                    %------------------------
                    rise_time = [rise_time; currPLoc - pLoc_neg_start];
            
                    % decay time
                    %------------------------
                    decay_time = [decay_time; pLoc_neg_end - currPLoc];
            
                    % slopes
                    %------------------------
                    s1 = polyfit( [pLoc_neg_start, currPLoc], [troughVal_start, peakVal], 1);
                    s2 = polyfit  ( [currPLoc, pLoc_neg_end],    [peakVal, troughVal_end], 1);
            
                    slope_inizio = [slope_inizio; s1(1)];
                    slope_fine = [slope_fine; s2(1)];
            
                end
            
            
                ttp = mean(ttp);
                width_base = mean(width_base);
                rise_time = mean(rise_time);
                decay_time = mean(decay_time);
                slope_inizio = mean(slope_inizio);
                slope_fine = mean(slope_fine);
            
            else
            
                ttp = 0;
                width_base = 0;
                rise_time = 0;
                decay_time = 0;
                slope_inizio = 0;
                slope_fine = 0;
            
            
            end
            

            % assign results
            app.EDA_Analysis.rawSignal = app.rawData;
            app.EDA_Analysis.filteredSignal = x;
            app.EDA_Analysis.EDA_MeanTonic = m_t;
            app.EDA_Analysis.EDA_MeanPhasic = m_f;
            % app.EDA_Analysis.EDA_MeanPhasic_meno30 = m_f_meno30; % (solo vr stroop coopcomp)
            app.EDA_Analysis.AUC = auc;
            app.EDA_Analysis.pNum = pNum;
            app.EDA_Analysis.pAmp = mean(pAmp); if isnan(app.EDA_Analysis.pAmp); app.EDA_Analysis.pAmp = 0; end
            app.EDA_Analysis.pProm = mean(pProm); if isnan(app.EDA_Analysis.pProm); app.EDA_Analysis.pProm = 0; end
            app.EDA_Analysis.pWidth = mean(pWidth); if isnan(app.EDA_Analysis.pWidth); app.EDA_Analysis.pWidth = 0; end
            app.EDA_Analysis.TTP = ttp;
            app.EDA_Analysis.width_base = width_base;
            app.EDA_Analysis.rise_time = rise_time;
            app.EDA_Analysis.decay_time = decay_time;
            app.EDA_Analysis.slope_inizio = slope_inizio;
            app.EDA_Analysis.slope_fine = slope_fine;

            % assign to app properties
            app.allPeak_start_time = allPeak_start_time;
            app.allPeak_start_y = allPeak_start_y;
            app.allPeak_end_time = allPeak_end_time;
            app.allPeak_end_y = allPeak_end_y;

            % Plot
            plot(app.axes_up, app.tx, app.rawData)

            plot(app.axes_down, app.filtered_tx, x)
            hold(app.axes_down, 'on')
            scatter(app.axes_down, allPeak_start_time, allPeak_start_y, 'ro', 'filled')
            scatter(app.axes_down, pLoc, pAmp, 'go', 'filled')
            scatter(app.axes_down, allPeak_end_time, allPeak_end_y, 'ro', 'filled')
            hold(app.axes_down, 'off')

            % coordinate with other interfaces
            app.DetectedpeaksLabel.Text = ['Detected peaks: ' num2str(pNum)];
            if pNum > 0
                app.ShowpeakNSpinner.Limits(2) = pNum;
            end
            app.yLims = app.axes_down.YLim;


        end

        % Value changed function: minpeakpromEditField
        function minpeakpromEditFieldValueChanged(app, event)
            app.peakMinProm = app.minpeakpromEditField.Value;
            
        end

        % Value changed function: mintroughpromEditField
        function mintroughpromEditFieldValueChanged(app, event)
            app.minTroughProm = app.mintroughpromEditField.Value;
            
        end

        % Value changed function: ShowpeakNSpinner
        function ShowpeakNSpinnerValueChanged(app, event)
            peak2plot_idx = app.ShowpeakNSpinner.Value;

            plot(app.axes_down, app.filtered_tx, app.filteredData)
            hold(app.axes_down, 'on')
            scatter(app.axes_down, app.allPeak_start_time, app.allPeak_start_y, 'ro', 'filled')
            scatter(app.axes_down, app.pLoc, app.pAmp, 'go', 'filled')
            scatter(app.axes_down, app.allPeak_end_time, app.allPeak_end_y, 'ro', 'filled')
            hold(app.axes_down, 'off')

            if peak2plot_idx > 0
                trough_start_loc = app.allPeak_start_time(peak2plot_idx);   
                trough_end_loc = app.allPeak_end_time(peak2plot_idx);

                app.axes_down.XLim = [trough_start_loc - 5, trough_end_loc + 5];

                xline(app.axes_up, app.pLoc, 'k');
                xline(app.axes_up, app.pLoc(peak2plot_idx), 'g');
    
            else
                cla(app.axes_down, 'reset')
                plot(app.axes_down, app.filtered_tx, app.filteredData);hold(app.axes_down, 'on')
                scatter(app.axes_down, app.allPeak_start_time, app.allPeak_start_y, 'ro', 'filled')
                scatter(app.axes_down, app.pLoc, app.pAmp, 'go', 'filled')
                scatter(app.axes_down, app.allPeak_end_time, app.allPeak_end_y, 'ro', 'filled')
                hold(app.axes_down, 'off')
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes_up = uiaxes(app.UIFigure);
            title(app.axes_up, 'Title')
            xlabel(app.axes_up, 'X')
            ylabel(app.axes_up, 'Y')
            zlabel(app.axes_up, 'Z')
            app.axes_down = uiaxes(app.UIFigure);
            title(app.axes_down, 'Title')
            xlabel(app.axes_down, 'X')
            ylabel(app.axes_down, 'Y')
            zlabel(app.axes_down, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.VariablesListBoxLabel = uilabel(app.UIFigure);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.VariablesListBox = uilistbox(app.UIFigure);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SamplingrateHzDropDownLabel = uilabel(app.UIFigure);
            app.SamplingrateHzDropDownLabel.Text = 'Sampling rate (Hz)';
            app.SamplingrateHzDropDown = uidropdown(app.UIFigure);
            app.SamplingrateHzDropDown.Items = {'250', '500', '1000'};
            app.SamplingrateHzDropDown.Value = '500';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.InterpolateButton = uibutton(app.UIFigure, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.ExporttoworkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoworkspaceButtonPushed, true);
            app.ExporttoworkspaceButton.Text = 'Export to workspace';
            app.HighPassEditFieldLabel = uilabel(app.UIFigure);
            app.HighPassEditFieldLabel.Text = 'HighPass';
            app.HighPassEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighPassEditField.ValueChangedFcn = createCallbackFcn(app, @HighPassEditFieldValueChanged, true);
            app.HighPassEditField.Value = 0.005;
            app.LowPassEditFieldLabel = uilabel(app.UIFigure);
            app.LowPassEditFieldLabel.Text = 'LowPass';
            app.LowPassEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPassEditField.ValueChangedFcn = createCallbackFcn(app, @LowPassEditFieldValueChanged, true);
            app.LowPassEditField.Value = 1;
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.minpeakpromEditFieldLabel = uilabel(app.UIFigure);
            app.minpeakpromEditFieldLabel.Text = 'min peak prom';
            app.minpeakpromEditField = uieditfield(app.UIFigure, 'numeric');
            app.minpeakpromEditField.ValueChangedFcn = createCallbackFcn(app, @minpeakpromEditFieldValueChanged, true);
            app.minpeakpromEditField.Value = 0.01;
            app.mintroughpromEditFieldLabel = uilabel(app.UIFigure);
            app.mintroughpromEditFieldLabel.Text = 'min trough prom';
            app.mintroughpromEditField = uieditfield(app.UIFigure, 'numeric');
            app.mintroughpromEditField.ValueChangedFcn = createCallbackFcn(app, @mintroughpromEditFieldValueChanged, true);
            app.mintroughpromEditField.Value = 0.01;
            app.DetectedpeaksLabel = uilabel(app.UIFigure);
            app.DetectedpeaksLabel.Text = 'Detected peaks: ';
            app.ShowpeakNSpinnerLabel = uilabel(app.UIFigure);
            app.ShowpeakNSpinnerLabel.Text = 'Show peak N°';
            app.ShowpeakNSpinner = uispinner(app.UIFigure);
            app.ShowpeakNSpinner.Limits = [0 Inf];
            app.ShowpeakNSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowpeakNSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Ispeziona_EDA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end